classdef FSAS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadMenu                      matlab.ui.container.Menu
        FSASMenu                      matlab.ui.container.Menu
        LoadFSASForceRampsMenu        matlab.ui.container.Menu
        LoadFSASForceVolumeMenu       matlab.ui.container.Menu
        NanoscopeMenu                 matlab.ui.container.Menu
        LoadNanoscopeForceRampMenu    matlab.ui.container.Menu
        LoadNanoscopeForceVolumeMenu  matlab.ui.container.Menu
        FSASForceSpectroscopyAnalysisSoftwareLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadFSASForceRampsMenu
        function LoadFSASForceRampsMenuSelected(app, event)
            addpath(genpath(pwd));
            [FileName, FilePath] = uigetfile('*.mat','Choose FSAS Force Ramps File');
            ForceRampAnalysis(FileName, FilePath, 1);
        end

        % Menu selected function: LoadFSASForceVolumeMenu
        function LoadFSASForceVolumeMenuSelected(app, event)
            addpath(genpath(pwd));
            [FileName, FilePath] =...
                uigetfile('*.mat','Choose FSAS Force Volume File');
            ForceVolumeAnalysis(FileName, FilePath, 1);
        end

        % Menu selected function: LoadNanoscopeForceRampMenu
        function LoadForceRampMenuSelected(app, event)
            addpath(genpath(pwd));
            [FileNames, FilePath] =...
                uigetfile('*.*','Choose Nanoscope Force Ramp Files','MultiSelect','on');
            ForceRampAnalysis(FileNames,FilePath, 2);
        end

        % Menu selected function: LoadNanoscopeForceVolumeMenu
        function LoadForceVolumeMenuSelected(app, event)
            addpath(genpath(pwd));
            [FileName, FilePath] =...
                uigetfile('*.*','Choose Nanoscope Force Volume File');
            ForceVolumeAnalysis(FileName, FilePath, 2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FSAS';
            app.LoadMenu = uimenu(app.UIFigure);
            app.LoadMenu.Text = 'Load';
            app.FSASMenu = uimenu(app.LoadMenu);
            app.FSASMenu.Text = 'FSAS';
            app.LoadFSASForceRampsMenu = uimenu(app.FSASMenu);
            app.LoadFSASForceRampsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFSASForceRampsMenuSelected, true);
            app.LoadFSASForceRampsMenu.Text = 'Force Ramp';
            app.LoadFSASForceVolumeMenu = uimenu(app.FSASMenu);
            app.LoadFSASForceVolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFSASForceVolumeMenuSelected, true);
            app.LoadFSASForceVolumeMenu.Text = 'Force Volume';
            app.NanoscopeMenu = uimenu(app.LoadMenu);
            app.NanoscopeMenu.Text = 'Nanoscope';
            app.LoadNanoscopeForceRampMenu = uimenu(app.NanoscopeMenu);
            app.LoadNanoscopeForceRampMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadForceRampMenuSelected, true);
            app.LoadNanoscopeForceRampMenu.Text = 'Force Ramp';
            app.LoadNanoscopeForceVolumeMenu = uimenu(app.NanoscopeMenu);
            app.LoadNanoscopeForceVolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadForceVolumeMenuSelected, true);
            app.LoadNanoscopeForceVolumeMenu.Text = 'Force Volume';
            app.FSASForceSpectroscopyAnalysisSoftwareLabel = uilabel(app.UIFigure);
            app.FSASForceSpectroscopyAnalysisSoftwareLabel.Text = {'FSAS'; 'Force Spectroscopy Analysis Software'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FSAS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end